classdef matlab_cloc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.EditField
        SaveResultsButton         matlab.ui.control.Button
        ExporttoWorkspaceButton   matlab.ui.control.Button
        TotalcountingLabel        matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        CountingLinesinCodeLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        PathEditField             matlab.ui.control.EditField
        PathEditFieldLabel        matlab.ui.control.Label
        SelectfolderButton        matlab.ui.control.Button
        SelectfileButton          matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function auxCount = fileCounting(app,myFile,myPath)

            auxCount.file_name = myFile ;
            auxCount.path = myPath ; 
            
            f = fopen( strcat( auxCount.path , auxCount.file_name ) ,'r') ;
            
            code = 0 ; 
            comment = 0 ; 
            emLine = 0 ;
            
            l = fgetl(f) ;
            
            while all(l > 0)     
            
                l = l( ~isspace(l) ) ; 
            
                if ~isempty(l)
            
                    if strcmp('%',l(1))
                
                        comment = comment + 1 ; 
                
                    else
                
                        code = code + 1 ;
                
                    end
            
                else
            
                    emLine = emLine + 1 ; 
            
                end
            
                l = fgetl(f) ;
            
            end
            
            clear ans l f 
            
            auxCount.Lines_of_Code = code ; 
            auxCount.Lines_of_Comments = comment ; 
            auxCount.Lines_Empty = emLine ; 
            auxCount.Total_Lines = auxCount.Lines_Empty + auxCount.Lines_of_Comments + auxCount.Lines_of_Code ; 
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            
            try

                warning('off')
            
                [f , path ] = uigetfile ; 
                app.PathEditField.Value = strcat(path,f) ; 
    
                Summary = fileCounting(app,f,path) ; 
                t = struct2table(Summary) ; 
                    
                app.UITable.Data = t ; 
    
                app.UITable2.Data = table( sum( cat(1,Summary.Lines_of_Code) ) , ...
                                           sum( cat(1,Summary.Lines_of_Comments) ) , ... 
                                           sum( cat(1,Summary.Lines_Empty) ) , ... 
                                           sum( cat(1,Summary.Total_Lines) ) ) ; 

                app.EditField.Value = '' ; 

            catch 

                app.EditField.Value = 'Nothing selected!' ; 

                set(app.UITable,'data',[])
                set(app.UITable2,'data',[])

                app.EditField_2.Value = '' ; 

            end

        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            
            try 

                warning('off')

                Summary = struct( 'file_name' , {} , ...
                      'path' , {} , ...
                      'Lines_of_Code' , {} , ...
                      'Lines_of_Comments' , {} , ...
                      'Lines_Empty' , {} , ...
                      'Total_Lines' , {} ) ;
                
                location = uigetdir ;
                app.PathEditField.Value = location ; 
                s = dir( strcat(location, '\**\*.m') ) ;

                app.EditField.Value = '' ; 
        
                for k = 1:size(s,1)
                
                    Summary(k) = fileCounting(app, s(k).name , strcat( s(k).folder , '\' ) ) ; 
                
                end
    
                t = struct2table(Summary) ; 
                app.UITable.Data = t ; 
    
                app.UITable2.Data = table( sum( cat(1,Summary.Lines_of_Code) ) , ...
                                           sum( cat(1,Summary.Lines_of_Comments) ) , ... 
                                           sum( cat(1,Summary.Lines_Empty) ) , ... 
                                           sum( cat(1,Summary.Total_Lines) ) ) ; 

                app.EditField.Value = '' ; 

            catch

                warning('off')

                app.EditField.Value = 'Nothing selected!' ; 

                set(app.UITable,'data',[])
                set(app.UITable2,'data',[])

                app.EditField_2.Value = '' ; 

            end
                
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)

            try 

                app.EditField_2.Value = 'Data saved!' ; 

                t = app.UITable.Data ; 
                Summary = {"Total Lines","",...
                           sum( cat(1,t.Lines_of_Code) ) , ...
                           sum( cat(1,t.Lines_of_Comments) ) , ... 
                           sum( cat(1,t.Lines_Empty) ) , ... 
                           sum( cat(1,t.Total_Lines) ) } ; 

                t = [t;Summary] ;

                assignin('base','Line_Counting',t) ;

                % app.EditField_2.Value = 'Data saved!' ; 

                % if ~exist('Line_Counting','var')
                % 
                %     error('no variable')
                % 
                % end

            catch

                warning('off')

                app.EditField_2.Value = 'Nothing saved!' ; 

                assignin('base','Line_Counting',[]) ;


            end

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
            try 

                app.EditField_2.Value = 'Data saved!' ; 

                t = app.UITable.Data ; 
                Summary = {"Total Lines","",...
                           sum( cat(1,t.Lines_of_Code) ) , ...
                           sum( cat(1,t.Lines_of_Comments) ) , ... 
                           sum( cat(1,t.Lines_Empty) ) , ... 
                           sum( cat(1,t.Total_Lines) ) } ; 

                t = [t;Summary] ;

                writetable(t,'Line_Counting.csv') ;

            catch

                warning('off')

                app.EditField_2.Value = 'Nothing saved!' ; 

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'file'; 'path'; 'Lines of Code'; 'Lines of Comments'; 'Empty lines'; 'Total'};
            app.UITable.RowName = {};
            app.CountingLinesinCodeLabel = uilabel(app.UIFigure);
            app.CountingLinesinCodeLabel.Text = 'Counting Lines in Code';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Lines of Code'; 'Lines of Comments'; 'Empty lines'; 'Total'};
            app.UITable2.RowName = {};
            app.TotalcountingLabel = uilabel(app.UIFigure);
            app.TotalcountingLabel.Text = 'Total counting:';
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_cloc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end